classdef LiquidityPoolSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        NameEditFieldLabel_3            matlab.ui.control.Label
        NameEditFieldLabel_2            matlab.ui.control.Label
        StartSimulationButton           matlab.ui.control.Button
        Fees                            matlab.ui.control.Spinner
        FeesLabel                       matlab.ui.control.Label
        NumberOfIterations              matlab.ui.control.Spinner
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        PriceEditField_B                matlab.ui.control.NumericEditField
        PriceEditField_A                matlab.ui.control.NumericEditField
        NameEditField_B                 matlab.ui.control.EditField
        BalanceEditField_B              matlab.ui.control.NumericEditField
        BalanceEditField_A              matlab.ui.control.NumericEditField
        NameEditField_A                 matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        TokenBLabel                     matlab.ui.control.Label
        TokenALabel                     matlab.ui.control.Label
        SimulationsettingsLabel         matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        SimulationresultsLabel          matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        Price_min                       matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        Price_max                       matlab.ui.control.Label
        avgLabel                        matlab.ui.control.Label
        Price_avg                       matlab.ui.control.Label
        minLabel_2                      matlab.ui.control.Label
        K_min                           matlab.ui.control.Label
        maxLabel_2                      matlab.ui.control.Label
        K_max                           matlab.ui.control.Label
        avgLabel_2                      matlab.ui.control.Label
        K_avg                           matlab.ui.control.Label
        PricePlot                       matlab.ui.control.UIAxes
        KPlot                           matlab.ui.control.UIAxes
        LiquidityPoolSimulationLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        nameT_a = "TokenA"
        nameT_b = "TokenB"
        Q_a = 100000
        Q_b = 10000
        P_b = 1
        numberOfIterations = 10000
        fees = 0.003
        T_a
        T_b
        varQ_a
        varQ_b
        varP_a
        varK       
    end
    
    methods (Access = private)
        
        
        function SetTokenAInitialPrice(app)
            value = (app.Q_a * app.Q_b) / (app.Q_a^2 + app.Q_a) * app.P_b;
            app.PriceEditField_A.Value = value;
        end
        
        function StartSimulation(app)
            app.T_a = Token(app.nameT_a);
            app.T_b = Token(app.nameT_b);
            [app.varQ_a, app.varQ_b, app.varP_a, app.varK] = simulateLiquidityPool(app.numberOfIterations, ...
                                                                                   app.T_a, app.T_b, app.Q_a, ...
                                                                                   app.Q_b, app.fees);           
            plot(app.PricePlot, app.varP_a, "Color", "blue");
            app.PricePlot.XLim = [0, app.numberOfIterations];
            plot(app.KPlot, app.varK, "Color", "green");
            app.KPlot.XLim = [0, app.numberOfIterations];
            app.KPlot.YLim = [max(min(app.varK), 1000), max(max(app.varK), 1000)];
            app.SetStatistics();
        end
        
        function SetStatistics(app)
            app.K_min.Text = string(min(app.varK));
            app.K_max.Text = string(max(app.varK));
            app.K_avg.Text = string(mean(app.varK));
            
            app.Price_min.Text = string(min(app.varP_a));
            app.Price_max.Text = string(max(app.varP_a));
            app.Price_avg.Text = string(mean(app.varP_a));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SetTokenAInitialPrice();
        end

        % Value changed function: BalanceEditField_A
        function BalanceEditField_A_ValueChanged(app, event)
            app.Q_a = app.BalanceEditField_A.Value;
            app.SetTokenAInitialPrice();
        end

        % Value changed function: BalanceEditField_B
        function BalanceEditField_B_ValueChanged(app, event)
            app.Q_b = app.BalanceEditField_B.Value;
            app.SetTokenAInitialPrice();
        end

        % Value changed function: PriceEditField_B
        function PriceEditField_B_ValueChanged(app, event)
            app.P_b = app.PriceEditField_B.Value;
            app.SetTokenAInitialPrice();
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.StartSimulation();
        end

        % Value changed function: NumberOfIterations
        function NumberOfIterations_ValueChanged(app, event)
            value = app.NumberOfIterations.Value;
            app.numberOfIterations = value;
        end

        % Value changed function: Fees
        function Fees_ValueChanged(app, event)
            value = app.Fees.Value;
            app.fees = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.NameEditFieldLabel_3 = uilabel(app.SettingsTab);
            app.NameEditFieldLabel_3.Text = 'Price ($)';
            app.NameEditFieldLabel_2 = uilabel(app.SettingsTab);
            app.NameEditFieldLabel_2.Text = 'Balance';
            app.StartSimulationButton = uibutton(app.SettingsTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = {'Start simulation'; ''};
            app.Fees = uispinner(app.SettingsTab);
            app.Fees.Step = 0.0001;
            app.Fees.ValueDisplayFormat = '%.4f';
            app.Fees.ValueChangedFcn = createCallbackFcn(app, @Fees_ValueChanged, true);
            app.Fees.Value = 0.003;
            app.FeesLabel = uilabel(app.SettingsTab);
            app.FeesLabel.Text = 'Fees (%)';
            app.NumberOfIterations = uispinner(app.SettingsTab);
            app.NumberOfIterations.Step = 1000;
            app.NumberOfIterations.ValueDisplayFormat = '%.0f';
            app.NumberOfIterations.ValueChangedFcn = createCallbackFcn(app, @NumberOfIterations_ValueChanged, true);
            app.NumberOfIterations.Value = 10000;
            app.NumberofiterationsSpinnerLabel = uilabel(app.SettingsTab);
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations';
            app.PriceEditField_B = uieditfield(app.SettingsTab, 'numeric');
            app.PriceEditField_B.ValueDisplayFormat = '%.3f';
            app.PriceEditField_B.ValueChangedFcn = createCallbackFcn(app, @PriceEditField_B_ValueChanged, true);
            app.PriceEditField_B.Value = 1;
            app.PriceEditField_A = uieditfield(app.SettingsTab, 'numeric');
            app.PriceEditField_A.ValueDisplayFormat = '%.3f';
            app.PriceEditField_A.Editable = 'off';
            app.NameEditField_B = uieditfield(app.SettingsTab, 'text');
            app.NameEditField_B.Value = 'TokenB';
            app.BalanceEditField_B = uieditfield(app.SettingsTab, 'numeric');
            app.BalanceEditField_B.ValueDisplayFormat = '%.2f';
            app.BalanceEditField_B.ValueChangedFcn = createCallbackFcn(app, @BalanceEditField_B_ValueChanged, true);
            app.BalanceEditField_B.Value = 10000;
            app.BalanceEditField_A = uieditfield(app.SettingsTab, 'numeric');
            app.BalanceEditField_A.ValueDisplayFormat = '%.2f';
            app.BalanceEditField_A.ValueChangedFcn = createCallbackFcn(app, @BalanceEditField_A_ValueChanged, true);
            app.BalanceEditField_A.Value = 100000;
            app.NameEditField_A = uieditfield(app.SettingsTab, 'text');
            app.NameEditField_A.Value = 'TokenA';
            app.NameEditFieldLabel = uilabel(app.SettingsTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.TokenBLabel = uilabel(app.SettingsTab);
            app.TokenBLabel.Text = 'Token B';
            app.TokenALabel = uilabel(app.SettingsTab);
            app.TokenALabel.Text = 'Token A';
            app.SimulationsettingsLabel = uilabel(app.SettingsTab);
            app.SimulationsettingsLabel.Text = 'Simulation settings';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SimulationresultsLabel = uilabel(app.ResultsTab);
            app.SimulationresultsLabel.Text = 'Simulation results';
            app.minLabel = uilabel(app.ResultsTab);
            app.minLabel.Text = 'min';
            app.Price_min = uilabel(app.ResultsTab);
            app.Price_min.Text = '0';
            app.maxLabel = uilabel(app.ResultsTab);
            app.maxLabel.Text = 'max';
            app.Price_max = uilabel(app.ResultsTab);
            app.Price_max.Text = '0';
            app.avgLabel = uilabel(app.ResultsTab);
            app.avgLabel.Text = 'avg';
            app.Price_avg = uilabel(app.ResultsTab);
            app.Price_avg.Text = '0';
            app.minLabel_2 = uilabel(app.ResultsTab);
            app.minLabel_2.Text = 'min';
            app.K_min = uilabel(app.ResultsTab);
            app.K_min.Text = '0';
            app.maxLabel_2 = uilabel(app.ResultsTab);
            app.maxLabel_2.Text = 'max';
            app.K_max = uilabel(app.ResultsTab);
            app.K_max.Text = '0';
            app.avgLabel_2 = uilabel(app.ResultsTab);
            app.avgLabel_2.Text = 'avg';
            app.K_avg = uilabel(app.ResultsTab);
            app.K_avg.Text = '0';
            app.PricePlot = uiaxes(app.ResultsTab);
            title(app.PricePlot, 'Price')
            xlabel(app.PricePlot, 'X')
            ylabel(app.PricePlot, 'Y')
            zlabel(app.PricePlot, 'Z')
            app.KPlot = uiaxes(app.ResultsTab);
            title(app.KPlot, 'K')
            xlabel(app.KPlot, 'X')
            ylabel(app.KPlot, 'Y')
            zlabel(app.KPlot, 'Z')
            app.LiquidityPoolSimulationLabel = uilabel(app.UIFigure);
            app.LiquidityPoolSimulationLabel.Text = 'Liquidity Pool Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiquidityPoolSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end